classdef slowcompensateUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        connectinstrumentButton     matlab.ui.control.Button
        collectcurrentvsVzVxButton  matlab.ui.control.Button
        magneticPIDSwitchLabel      matlab.ui.control.Label
        magneticPIDSwitch           matlab.ui.control.Switch
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes5                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: connectinstrumentButton
        function connectinstrumentButtonPushed(app, event)
           app.InstrumentconnectButton.BackgroundColor=[1.00,0.00,0.00];
           connectinstrument; 
           app.InstrumentconnectButton.BackgroundColor=[0.00,1.00,0.00];
        end

        % Button pushed function: collectcurrentvsVzVxButton
        function collectcurrentvsVzVxButtonPushed(app, event)
            app.collectcurrentvsVzVxButtonPushed.BackgroundColor=[1.00,0.00,0.00];
            global Vxp1;
            global Vxp2;
            global Vxset;
            [Vxp1,Vxp2,Vxset]=currentvsVzVxUI(app)
            app.collectcurrentvsVzVxButtonPushed.BackgroundColor=[0.00,1.00,0.00];
        end

        % Value changed function: magneticPIDSwitch
        function magneticPIDSwitchValueChanged(app, event)
            global PIDvalue
            global Vxp1;
            global Vxp2;
            global Vxset;
            PIDvalue = app.magneticPIDSwitch.Value;
            slowcompensateUI(Vxp1,Vxp2,Vxset,PIDvalue,app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.connectinstrumentButton = uibutton(app.UIFigure, 'push');
            app.connectinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @connectinstrumentButtonPushed, true);
            app.connectinstrumentButton.Text = 'connect instrument';
            app.collectcurrentvsVzVxButton = uibutton(app.UIFigure, 'push');
            app.collectcurrentvsVzVxButton.ButtonPushedFcn = createCallbackFcn(app, @collectcurrentvsVzVxButtonPushed, true);
            app.collectcurrentvsVzVxButton.Text = 'collect current vs Vz Vx';
            app.magneticPIDSwitchLabel = uilabel(app.UIFigure);
            app.magneticPIDSwitchLabel.Text = 'magnetic PID';
            app.magneticPIDSwitch = uiswitch(app.UIFigure, 'slider');
            app.magneticPIDSwitch.ValueChangedFcn = createCallbackFcn(app, @magneticPIDSwitchValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = slowcompensateUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end